classdef app_EKG_projekt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        AnalzaEKGTab                  matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        spustit_zaznam                matlab.ui.control.Button
        ZastavitButton                matlab.ui.control.Button
        AnalzaButton                  matlab.ui.control.Button
        zprava_textak                 matlab.ui.control.StateButton
        vypln_kurak                   matlab.ui.control.CheckBox
        semafor2                      matlab.ui.control.Lamp
        semafor3                      matlab.ui.control.Lamp
        RychlostpehrvnSliderLabel     matlab.ui.control.Label
        rychlost_prehravani           matlab.ui.control.Slider
        TepovfrekvenceEditFieldLabel  matlab.ui.control.Label
        tepova_frekvence              matlab.ui.control.NumericEditField
        PrmrntepovfrekvenceEditFieldLabel  matlab.ui.control.Label
        prumer_tep                    matlab.ui.control.NumericEditField
        vypln_jmeno                   matlab.ui.control.EditField
        vek_vypln                     matlab.ui.control.NumericEditField
        vypln_choroby                 matlab.ui.control.TextArea
        PejtnazatekButton             matlab.ui.control.Button
        analyzaR_2                    matlab.ui.control.TextArea
        analyza_R_kmity               matlab.ui.control.TextArea
        Panel_6                       matlab.ui.container.Panel
        AnalzaEKGzezznamuLabel        matlab.ui.control.Label
        IDpacientaEditFieldLabel      matlab.ui.control.Label
        ID_num                        matlab.ui.control.EditField
        vypln_prijmeni                matlab.ui.control.EditField
        Image                         matlab.ui.control.Image
        peaks_zobrazovat              matlab.ui.control.StateButton
        Panel_7                       matlab.ui.container.Panel
        rytmus                        matlab.ui.control.TextArea
        semafor1                      matlab.ui.control.Lamp
        label_jmeno                   matlab.ui.control.Label
        label_vek                     matlab.ui.control.Label
        label_choroby                 matlab.ui.control.Label
        label_prijmeni                matlab.ui.control.Label
        vypln_analyza_tep             matlab.ui.control.TextArea
        semafor4                      matlab.ui.control.Lamp
        NastavenTab                   matlab.ui.container.Tab
        nacist_data                   matlab.ui.control.Button
        prijmeni_p                    matlab.ui.control.CheckBox
        ZobrazovatLabel               matlab.ui.control.Label
        jmeno_p                       matlab.ui.control.CheckBox
        vek_p                         matlab.ui.control.CheckBox
        prodelane_p                   matlab.ui.control.CheckBox
        graf_barva                    matlab.ui.container.ButtonGroup
        cervena_g                     matlab.ui.control.RadioButton
        zluta_g                       matlab.ui.control.RadioButton
        modra_g                       matlab.ui.control.RadioButton
        peak_barva                    matlab.ui.container.ButtonGroup
        cervena_p                     matlab.ui.control.RadioButton
        zluta_p                       matlab.ui.control.RadioButton
        modra_p                       matlab.ui.control.RadioButton
        kurak                         matlab.ui.control.CheckBox
        Panel_4                       matlab.ui.container.Panel
        NastavenLabel                 matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        SloupeczekterhochcetenastasLabel  matlab.ui.control.Label
        cas_sloupec_pole              matlab.ui.control.NumericEditField
        SloupeczekterhochcetenastdataLabel  matlab.ui.control.Label
        data_sloupec_pole             matlab.ui.control.NumericEditField
        PoetzobrazenchsekundLabel     matlab.ui.control.Label
        pocet_sekund_zobrezeni        matlab.ui.control.NumericEditField
        VypisovatnefyziologickhodnotydotextovhosouboruSwitchLabel  matlab.ui.control.Label
        vypisovat_do_souboru          matlab.ui.control.Switch
        Image3                        matlab.ui.control.Image
        Image3_2                      matlab.ui.control.Image
        Image3_4                      matlab.ui.control.Image
        Image3_5                      matlab.ui.control.Image
        Image3_6                      matlab.ui.control.Image
        Image3_7                      matlab.ui.control.Image
        NvratdoautorizaceButton       matlab.ui.control.Button
        UpozorovatnanefyziologickhodnotyvyskakovacmoknemSwitchLabel  matlab.ui.control.Label
        oznamovat                     matlab.ui.control.Switch
    end

    
    properties (Access = public)
        EKG % Description
        cas
        krivka1
        m
        n
        maxY
        minY
        rychlost
        pockej
        X
        Y
        frekvence
        tep
        stop
        pocet_QRS
        soucet
        prumer
        odchylky
        R
        prumer_odchylka
        suma_odchylka
        pocet_rozdilu
        databaze
        prijmeni
        jmeno
        rok
        mesic
        den
        sys
        dias
        vyska
        vaha
        data
        ID_pacienta
        ID_pacienta_cell
        ID_cislo
        zavolat_autorizaci
        rytmus_alert
        spusteno
        otevrit_nastaveni
        CallingApp
        zmena
        barva_krivky
        barva_peaku
        peaky
        vypisuj
        cas_sloupec
        ekg_sloupec
        navrat_autorizace
        pocet_vzorku
        cas_novy
        oznamuj_nefyzio
        choroby_prodelane
        je_kurak
        prumerny_R
        odchylka_vyska
        suma_odchylek_R
        prumerna_odchylka_R
        analyza
    end
    
    methods (Access = public)
        
        function graf(app)
                 
            plot(app.UIAxes, app.cas.Value, app.krivka1.Value, 'color', app.barva_krivky.Value)
            
            xlabel(app.UIAxes, 'čas{\it t} [s]')
            ylabel(app.UIAxes, 'napětí{\it U} [mV]')
            
            xlim(app.UIAxes, [app.m.Value  app.n.Value])
            
            % najde maximální a minimální hodnoty signálu a zobrazí adekvátní počet
            % hodnot na ose Y
            app.maxY.Value = max(app.krivka1.Value);
            app.minY.Value = min(app.krivka1.Value);
            
            ylim(app.UIAxes, [app.minY.Value-0.02 app.maxY.Value+0.02])
                     
            hold(app.UIAxes, 'on')
                                   
            if app.X.Value(app.tep.Value) < app.m.Value + 1
                app.tep.Value = app.tep.Value + 1; % přeskočí na další R peak pro výpočet tepové frekvence
            end
            
            app.frekvence.Value = 1 / (app.X.Value(app.tep.Value + 1) - app.X.Value(app.tep.Value)) * 60;
            
            app.tepova_frekvence.Value = app.frekvence.Value;
            
            
        end
        
        function prumerna_tepova_frekvence(app) 
            % sečte všechny vzdálenosti sousedních 2 R peaků a vydělí je
            % jejich početem - 1
            
            app.pocet_QRS.Value = length(app.X.Value);
            app.soucet.Value = 0;
            
            for j = 1:app.pocet_QRS.Value - 1
                app.soucet.Value = app.soucet.Value + app.X.Value(j+1) - app.X.Value(j);
            end
            
            app.prumer.Value = app.soucet.Value / (app.pocet_QRS.Value - 1);
            
            app.prumer_tep.Value = 1 / (app.X.Value(app.tep.Value + 1) - app.X.Value(app.tep.Value)) * 60;
        end
        
        function analyza_R_kmitu(app)
            
            app.prumerny_R.Value = sum(app.Y.Value)/app.pocet_QRS.Value; % průměrná výška peaku
            app.odchylka_vyska.Value = zeros(app.pocet_QRS.Value, 1); % vytvoří vektor pro naplnění hodnotami
            app.suma_odchylek_R.Value = 0; % inicializace proměnné pro součet
            
            for j = 1:app.pocet_QRS.Value
                
                app.odchylka_vyska.Value(j) = abs(app.prumerny_R.Value - app.Y.Value(j));
                app.suma_odchylek_R.Value = app.suma_odchylek_R.Value + app.odchylka_vyska.Value(j);
                
                % tím 0.3 si nejsem vůbec jistá, je to dost random hodnota
                if app.odchylka_vyska.Value < 0.3
                    app.odchylka_vyska.Value(j) = 0; % vynulování zanedbatelných odchylek
                end
            
            end
            
            app.prumerna_odchylka_R.Value = app.suma_odchylek_R.Value/app.pocet_QRS.Value;
            
            if app.odchylka_vyska.Value == zeros(app.pocet_QRS.Value, 1)
                app.analyza_R_kmity.Value = 'Pravidelká výška R kmitů';
            else
                app.analyza_R_kmity.Value = 'Nepravidelná výška R kmitů!';
                app.semafor2.Color = [1 0 0];
                app.analyza.Value(1,2) = 1;
            end
            
            if app.prumerny_R.Value > 2.5
                app.analyzaR_2.Value = 'Příliš vysoká amplituda R kmitu';
                app.semafor3.Color = [1 0 0];
                app.analyza.Value(1,3) = 1;
            else
                app.analyzaR_2.Value = 'Amplituda R kmitu v normě';
            end
                
                        
        end
        
        function analyza_tep_f(app)
            
            if app.prumer_tep.Value > 90
                app.vypln_analyza_tep.Value = 'Příliš vysoká tepová frekvence! TACHYKARDIE';
                app.semafor4.Color = [1 0 0];
                app.analyza.Value(1,4) = 1;
            elseif app.prumer_tep.Value >= 55
                app.vypln_analyza_tep.Value = 'Tepová frekvence v normě';
            else
                app.vypln_analyza_tep.Value = 'Příliš nízká tepová frekvence! BRADYKARDIE';
                app.semafor4.Color = [1 0 0];
                app.analyza.Value(1,4) = 1;
            end
            
        end
        
        function analyza_srdecni_rytmus(app)
            app.pocet_rozdilu.Value = app.pocet_QRS.Value - 1;
            
            app.R.Value = zeros(1, app.pocet_rozdilu.Value); % připraví si nulový vektor, který bude plnit hodnotami
            
            app.suma_odchylka.Value = 0;
            
            for j = 1:app.pocet_rozdilu.Value
                
                % zjistí o kolik se liší hodnota j od průměru
                app.R.Value(j) = abs(app.prumer.Value - (app.X.Value(j+1) - app.X.Value(j)));
                
                % sčítá všechny odchylky
                app.suma_odchylka.Value = app.suma_odchylka.Value + app.R.Value(j);
                                               
            end
            
            % zprůměruje odchylku
            app.prumer_odchylka.Value = app.suma_odchylka.Value / app.pocet_rozdilu.Value;
            
            for j = 1:app.pocet_rozdilu.Value
                
                % vynuluje odchylky, které se příliš neliší od průměru
                if app.R.Value(j) < 0.16
                        
                        app.R.Value(j) = 0;
                        
                end
                
            end                                                     
            % porovná, jestli jsou všechny odchylky nulové
            if app.R.Value(1,:) == zeros(1, app.pocet_rozdilu.Value)
                
                app.rytmus.Value = 'Srdeční rytmus v normě';
                
                app.semafor1.Color = [0 1 0];
                
            else                                               
                
                app.rytmus.Value = 'Nepravidelný srdeční rytmus!';
                
                app.semafor1.Color = [1 0 0];
                
                app.analyza.Value(1,1) = 1;
                                                                                     
            end
        end
        
        function odznova(app)
        
            hold(app.UIAxes, 'off') % aby nezůstal zobrazený starý graf při novém vykreslování
            
            app.EKG.Value = load('EKG_projekt.txt');
            
            % sloupce, ze kterých se načítají sekundy a hodnoty
            app.cas_sloupec.Value = app.cas_sloupec_pole.Value;                                               
            app.ekg_sloupec.Value = app.data_sloupec_pole.Value;
            
            app.cas.Value = app.EKG.Value(:, app.cas_sloupec.Value); % hodnoty na ose X
            app.krivka1.Value = app.EKG.Value(:, app.ekg_sloupec.Value); % hodnoty na ose Y
                        
            app.m.Value = app.cas.Value(1); % najde začátek
            app.n.Value = app.m.Value + app.pocet_sekund_zobrezeni.Value; % zobrazí požadovaný počet s
            
            app.tep.Value = 1;
            
            % hledá R peaky
            [app.Y.Value, app.X.Value] = findpeaks(app.krivka1.Value, app.cas.Value, 'MinPeakHeight', 0.22);
            
            grid(app.UIAxes, 'on')
            grid(app.UIAxes, 'minor')
            
            prumerna_tepova_frekvence(app)
            
            % inicializace všemožných proměnných
            app.pockej.Value = 1;     
            app.spusteno.Value = 0;
            app.zmena.Value = 0;
            app.barva_krivky.Value = [1 0.4 0.6];
            app.barva_peaku.Value = [0.4 0.9 1];
            app.peaky.Value = 0;
            app.vypisuj.Value = 1;
            app.oznamuj_nefyzio.Value = 1;
            
            graf(app)
            
        end

    end
          

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ID_prenos)
            app.ID_num.Value = ID_prenos; % uloží ID poslané z autorizace
            zmena_ID(app) % zavolá funkci pro vytažení dat z excelovské tabulky
                                    
            odznova(app)      
        end

        % Button pushed function: spustit_zaznam
        function spustit(app, event)
            app.stop.Value = 0;
            app.spusteno.Value = 1;
            set(app.spustit_zaznam, 'Enable', 'off');
            set(app.ZastavitButton, 'Enable', 'on');
            
            while app.n.Value <= app.cas.Value(end) && app.stop.Value ~= 1
                % graf se překresluje o sekundu dopředu dokud nedojede nakonec nebo se nezmáčkne tlačítko zastavit         
                pause(app.pockej.Value); % defaultně čeká sekundu
                
                app.m.Value = app.m.Value + 1;
                app.n.Value = app.n.Value + 1;
                
                graf(app)
                
            end
        end

        % Value changed function: rychlost_prehravani
        function rychlost_prehravaniValueChanged(app, event)
            app.rychlost.Value = app.rychlost_prehravani.Value;
            app.pockej.Value = 1/app.rychlost.Value; % převrácená hodnota frekvence je perioda
        end

        % Button pushed function: nacist_data
        function nova_data(app, event)
            [soubor, cesta] = uigetfile({'*.txt;*.csv;*.xls;*.xlsx', 'EKG data pacienta'});
            nazev_souboru = [cesta, soubor];
            
            try
                app.EKG.Value = load(nazev_souboru);
            catch ME
                uialert(app.UIFigure, ME.message, 'Nelze načíst');
                return;
            end
            
            graf(app)
        end

        % Button pushed function: ZastavitButton
        function zastavit(app, event)
            app.stop.Value = 1;
            app.spusteno.Value = 0;
            set(app.spustit_zaznam, 'Enable', 'on');
            set(app.ZastavitButton, 'Enable', 'of');
        end

        % Button pushed function: AnalzaButton
        function pravidelnost(app, event)
            % analyzuje, jestli je průběh EKG v normě
            
            app.analyza.Value = zeros(1,4);
            
            % zobrazí zelená světýlka
            set(app.semafor1, 'Visible', 'on');
            set(app.semafor2, 'Visible', 'on');
            set(app.semafor3, 'Visible', 'on');
            set(app.semafor4, 'Visible', 'on');
                                           
            analyza_srdecni_rytmus(app)
            analyza_R_kmitu(app)
            analyza_tep_f(app)
            
            if app.oznamuj_nefyzio.Value == 1  && (app.analyza.Value(1,1) ~= 0 || app.analyza.Value(1,2) ~= 0 || app.analyza.Value(1,3) ~= 0 || app.analyza.Value(1,4) ~= 0)
                    app.rytmus_alert = app_nepravidelny_rytmus(app.analyza.Value); % vyskakovací okno
            end
            
            if (app.vypisuj.Value == 1)  && (app.analyza.Value(1,1) ~= 0 || app.analyza.Value(1,2) ~= 0 || app.analyza.Value(1,3) ~= 0 || app.analyza.Value(1,4) ~= 0)
                
                    textak = fopen('nefyziologicke_hodnoty.txt', 'w');
                    
                    fprintf(textak, 'velikosti odchylek délky jednoho cyklu od průměru a sekunda, kde se nacházejí:\n');
                    y = 0;
                    for j = 1:app.pocet_rozdilu.Value
                        
                        if app.R.Value(j) ~= 0
                            
                            % vypíše do souboru všechny nenulové
                            % odchylky společně s časem, kde se
                            % necházejí
                            fprintf(textak, '%d. %f %f\n',j, app.R.Value(j), app.X.Value(j));   
                            y = 1;
                        end                                
                    end
                    
                    if y == 0
                            fprintf(textak, 'žádné nefyziologické odchylky\n');
                    end
                    
                    fprintf(textak, 'tepová frekvence: %f tepů za minutu\n', app.prumer_tep.Value);
                        fprintf(textak, 'průměrná amplituda R kmitů: %f mV\n', app.prumerny_R.Value);
                        
                        fprintf(textak, 'velikosti odchylek amplitudy R kmitu od průměru a sekunda, kde se nacházejí:\n');
                        x = 0;
                        for k = 1:length(app.odchylka_vyska.Value)                                                                    
                            
                            if app.odchylka_vyska.Value(k) ~= 0
                                
                                fprintf(textak, '%d. %f %f\n',k, app.odchylka_vyska.Value(k), app.X.Value(k));
                                x = 1;
                            end                                  
                            
                        end
                        if x == 0
                            fprintf(textak, 'žádné nefyziologické odchylky');
                        end
                    fclose(textak);
             end
            
            % možnost vygenerovat texťák jako zprávu z
            % vyšetření
            set(app.zprava_textak, 'visible', 'on');
                    
                    
        end

        % Value changed function: ID_num
        function zmena_ID(app, event)
            % přečte celou excelovskou 'databázi'
            app.databaze.Value = readtable('databaze_pacientu.xlsx');
            
            % aby se dalo s daty lépe pracovat (např. je porovnávat)
            app.data = table2struct(app.databaze.Value);
            
            % políčko s ID je textové, protože nechci, aby se ID
            % zobrazovalo jako 1,024863 * 10 ^ 6
            app.ID_cislo.Value = str2double(app.ID_num.Value);
            
            pocitadlo = 0;
            
                        
            for j = 1:height(app.databaze.Value)
                
                % vytáhnu ze structu, aby se dalo porovnávat
                app.ID_pacienta.Value = app.data(j).ID;
                
                if app.ID_pacienta.Value == app.ID_cislo.Value
                    
                    % vytažení dat ze structu
                    app.prijmeni.Value = app.data(j).Prijmeni;
                    app.jmeno.Value = app.data(j).Jmeno;
                    app.rok.Value = app.data(j).DatumNarozeni;
                    app.sys.Value = app.data(j).Sys;
                    app.dias.Value = app.data(j).Dias;
                    app.vyska.Value = app.data(j).Vyska;
                    app.vaha.Value = app.data(j).Vaha;
                    app.choroby_prodelane.Value = app.data(j).Prodelane;
                    app.je_kurak.Value = app.data(j).Kurak;
                    
                    app.vypln_prijmeni.Value = app.prijmeni.Value;
                    app.vypln_jmeno.Value = app.jmeno.Value;
                    
                    % výpočet věku pacienta
                    ted = datetime;
                    [ted_rok, ted_mesic, ted_den] = ymd(ted); %protože čas je mi jedno
                    [nar_rok, nar_mesic, nar_den] = ymd(app.rok.Value);
                                                          
                    vek_rok = ted_rok-nar_rok;
                    
                    % zohlední měsíce a dny ve výpočtu věku
                    vek_mesic = ted_mesic - nar_mesic;
                    vek_den = ted_den - nar_den;
                                        
                    if vek_den <= 0 % porovná aktuální den v měsíci se dnem narozenin
                        vek_mesic = vek_mesic - 1; % pokud má narozeniny v pozdějším datu, nesmíme tento měsíc počítat
                    end
                    if vek_mesic <= 0 % porovná měsíc narození s autuálním měsícem
                        vek_rok = vek_rok - 1; % pokud ještě tento rok neměl narozeniny, je o rok mladší
                    end
                                        
                    app.vek_vypln.Value = vek_rok;
                    
                    app.vypln_choroby.Value = app.choroby_prodelane.Value;
                    app.vypln_kurak.Value = app.je_kurak.Value;
             
                    pocitadlo = 1;                    
                end
                
            end
            
            if pocitadlo == 0
                    % nahlásí chybu, když je zadané chybné ID (stát by se
                    % nemělo, ale pro jistotu)
                    app.ID_num.Value = 'CHYBA!';
                    
             end
        end

        % Button pushed function: PejtnazatekButton
        function zacatek(app, event)
            % vrátí se zpět na začátek signálu
            
            app.m.Value = app.cas.Value(1);
            app.n.Value = app.m.Value + app.pocet_sekund_zobrezeni.Value;
            
            graf(app)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            switch event.Key
                case 'leftarrow' % posunutí o sekundu dozadu
                    app.m.Value = app.m.Value - 1;
                    app.n.Value = app.n.Value - 1;
                case 'rightarrow' % posunutí o sekundu dopředu
                    app.m.Value = app.m.Value + 1;
                    app.n.Value = app.n.Value + 1;
                case 'space' % spustit nebo zastavit přehrávání
                    if app.spusteno.Value == 0
                        spustit(app)
                    else
                        zastavit(app)
                    end
                case 'uparrow' % zrychluje přehrávání
                    if app.rychlost_prehravani.Value <= 1.9 %nefunguje až do kraje
                        app.rychlost_prehravani.Value = app.rychlost_prehravani.Value + 0.1;
                    end
                case 'downarrow' % zpomaluje přehrávání
                    if app.rychlost_prehravani.Value > 0.6
                        app.rychlost_prehravani.Value = app.rychlost_prehravani.Value - 0.1;
                    end
            end
            
            graf(app);
        end

        % Value changed function: zprava_textak
        function generovat(app, event)
            zprava = fopen('zparava_o_vysetreni.txt', 'w');
            vysledek_rytmu = char(app.rytmus.Value); % nutnost převést na char, jinak hlásí chybu
            vysledek_R1 = char(app.analyza_R_kmity.Value);
            vysledek_R2 = char(app.analyzaR_2.Value);
            vysledek_tep = char(app.vypln_analyza_tep.Value);
            datum = datestr(app.rok.Value); % vytvoření stringu z typu datearray
            fprintf(zprava, 'Příjmení: %s\nJméno: %s\nDatum narození: %s\nPrůměrná tepová frekvence: %f/min, %s\nAnalýza srdečního rytmu: %s\nAnalýza R kmitu: %s, %s',app.prijmeni.Value, app.jmeno.Value, datum, app.prumer_tep.Value, vysledek_tep, vysledek_rytmu, vysledek_R1, vysledek_R2);                      
        end

        % Value changed function: prijmeni_p
        function prijmeni_zmena(app, event)
            if app.prijmeni_p.Value == 1
                set(app.vypln_prijmeni, 'Visible', 'on')
                set(app.label_prijmeni, 'Visible', 'on')
            else
                set(app.vypln_prijmeni, 'Visible', 'off')
                set(app.label_prijmeni, 'Visible', 'off')
            end            
        end

        % Value changed function: jmeno_p
        function jmeno_zmena(app, event)
            if app.jmeno_p.Value == 1
                set(app.vypln_jmeno, 'Visible', 'on')
                set(app.label_jmeno, 'Visible', 'on')
            else
                set(app.vypln_jmeno, 'Visible', 'off')
                set(app.label_jmeno, 'Visible', 'off')
            end            
        end

        % Value changed function: vek_p
        function zmena_vek(app, event)
            if app.vek_p.Value == 1
                set(app.vek_vypln, 'Visible', 'on')
                set(app.label_vek, 'Visible', 'on')
            else
                set(app.vek_vypln, 'Visible', 'off')
                set(app.label_vek, 'Visible', 'off')
            end            
        end

        % Value changed function: prodelane_p
        function zmena_chorby(app, event)
            if app.prodelane_p.Value == 1
                set(app.vypln_choroby, 'Visible', 'on')
                set(app.label_choroby, 'Visible', 'on')
            else
                set(app.vypln_choroby, 'Visible', 'off')
                set(app.label_choroby, 'Visible', 'off')
            end            
        end

        % Value changed function: kurak
        function zmena_kurak(app, event)
           if app.kurak.Value == 1
                set(app.vypln_kurak, 'Visible', 'on')
            else
                set(app.vypln_kurak, 'Visible', 'off')
           end            
        end

        % Selection changed function: graf_barva
        function berva_zmena(app, event)
            if app.graf_barva.SelectedObject == app.cervena_g
                app.barva_krivky.Value = [1 0.4 0.6];
            elseif app.graf_barva.SelectedObject == app.modra_g
                app.barva_krivky.Value = [0.4 0.9 1];
            else
                app.barva_krivky.Value = [1 0.9 0.1];
            end
            
            graf(app)
        end

        % Selection changed function: peak_barva
        function barva_peak_zmena(app, event)
            if app.peak_barva.SelectedObject == app.cervena_p
                app.barva_peaku.Value = [1 0.4 0.6];
            elseif app.peak_barva.SelectedObject == app.modra_p
                app.barva_peaku.Value = [0.4 0.9 1];
            else
                app.barva_krivky.Value = [1 0.9 0.1];
            end
            
            graf(app)
        end

        % Value changed function: peaks_zobrazovat
        function peaks(app, event)
            if app.peaks_zobrazovat.Value == 1
                %app.peaky.Value = 1;
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, app.X.Value, app.Y.Value, '*', 'color', app.barva_peaku.Value)                
            else
                %app.peaky.Value = 0;
                hold(app.UIAxes, 'off')
            end
            graf(app)
        end

        % Value changed function: vypisovat_do_souboru
        function vypisuj_do_souboru(app, event)
            if app.vypisovat_do_souboru.Value == 1
                app.vypisuj.Value = 1;
            else
                app.vypisuj.Value = 0;
            end            
        end

        % Value changed function: cas_sloupec_pole
        function sloupec_cas_zmena(app, event)
            %nefunguje dobře, ještě doladit
            
            if app.cas_sloupec_pole.Value <= 0
                app.cas_sloupec_pole.Value = 1;
            elseif app.cas_sloupec.Value > length(app.EKG.Value(1,:))
                %app.cas_sloupec.Value = length(app.EKG.Value(1,:));
                app.cas_sloupec_pole.Value = length(app.EKG.Value(1,:));
            end
            
            grid(app.UIAxes, 'minor') % aby mřížka nezmizela stejným příkazem v odznova(app)
            odznova(app)
        end

        % Value changed function: data_sloupec_pole
        function data_sloupec_zmena(app, event)
            %nefunguje dobře, ještě doladit
            
            if app.data_sloupec_pole.Value <= 0
                app.data_sloupec_pole.Value = 1;
            elseif app.data_sloupec_pole.Value > length(app.EKG.Value(1,:))
                %app.ekg_sloupec.Value = length(app.EKG.Value(1,:));
                app.data_sloupec_pole.Value = length(app.EKG.Value(1,:));
            end
                        
            grid(app.UIAxes, 'minor') % opět zachování mřížky
            odznova(app)
        end

        % Button pushed function: NvratdoautorizaceButton
        function navrat(app, event)
            app.navrat_autorizace = app_autorizace();
            delete(app)
        end

        % Value changed function: pocet_sekund_zobrezeni
        function zmena_sekundy(app, event)
            % změní počet zobrazovaných sekund
            app.n.Value = app.m.Value + app.pocet_sekund_zobrezeni.Value;
            
            graf(app)            
        end

        % Value changed function: oznamovat
        function oznamuj(app, event)
            if app.oznamovat.Value == 1
                app.oznamuj_nefyzio.Value = 1;
            else
                app.oznamuj_nefyzio.Value = 0;
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AnalzaEKGTab = uitab(app.TabGroup);
            app.AnalzaEKGTab.Title = 'Analýza EKG';
            app.UIAxes = uiaxes(app.AnalzaEKGTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Čas t [s]')
            ylabel(app.UIAxes, 'Napětí U [mV]')
            app.spustit_zaznam = uibutton(app.AnalzaEKGTab, 'push');
            app.spustit_zaznam.ButtonPushedFcn = createCallbackFcn(app, @spustit, true);
            app.spustit_zaznam.Text = 'Spustit záznam v čase';
            app.ZastavitButton = uibutton(app.AnalzaEKGTab, 'push');
            app.ZastavitButton.ButtonPushedFcn = createCallbackFcn(app, @zastavit, true);
            app.ZastavitButton.Enable = 'off';
            app.ZastavitButton.Text = 'Zastavit';
            app.AnalzaButton = uibutton(app.AnalzaEKGTab, 'push');
            app.AnalzaButton.ButtonPushedFcn = createCallbackFcn(app, @pravidelnost, true);
            app.AnalzaButton.Icon = 'srdce2.png';
            app.AnalzaButton.Text = 'Analýza';
            app.zprava_textak = uibutton(app.AnalzaEKGTab, 'state');
            app.zprava_textak.ValueChangedFcn = createCallbackFcn(app, @generovat, true);
            app.zprava_textak.Visible = 'off';
            app.zprava_textak.Text = {'Vygenerovat'; 'zprávu'};
            app.vypln_kurak = uicheckbox(app.AnalzaEKGTab);
            app.vypln_kurak.Visible = 'off';
            app.vypln_kurak.Text = 'Kuřák';
            app.semafor2 = uilamp(app.AnalzaEKGTab);
            app.semafor2.Visible = 'off';
            app.semafor3 = uilamp(app.AnalzaEKGTab);
            app.semafor3.Visible = 'off';
            app.RychlostpehrvnSliderLabel = uilabel(app.AnalzaEKGTab);
            app.RychlostpehrvnSliderLabel.Text = 'Rychlost přehrávání';
            app.rychlost_prehravani = uislider(app.AnalzaEKGTab);
            app.rychlost_prehravani.Limits = [0.5 2];
            app.rychlost_prehravani.MajorTicks = [0.5 0.75 1 1.25 1.5 1.75 2];
            app.rychlost_prehravani.MajorTickLabels = {'0.5', '0.75', '1', '1.25', '1.5', '1.75', '2'};
            app.rychlost_prehravani.ValueChangedFcn = createCallbackFcn(app, @rychlost_prehravaniValueChanged, true);
            app.rychlost_prehravani.MinorTicks = [0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1 1.05 1.1 1.15 1.2 1.25 1.3 1.35 1.4 1.45 1.5 1.55 1.6 1.65 1.7 1.75 1.8 1.85 1.9 1.95 2];
            app.rychlost_prehravani.Value = 1;
            app.TepovfrekvenceEditFieldLabel = uilabel(app.AnalzaEKGTab);
            app.TepovfrekvenceEditFieldLabel.Text = 'Tepová frekvence';
            app.tepova_frekvence = uieditfield(app.AnalzaEKGTab, 'numeric');
            app.tepova_frekvence.Editable = 'off';
            app.PrmrntepovfrekvenceEditFieldLabel = uilabel(app.AnalzaEKGTab);
            app.PrmrntepovfrekvenceEditFieldLabel.Text = 'Průměrná tepová frekvence';
            app.prumer_tep = uieditfield(app.AnalzaEKGTab, 'numeric');
            app.prumer_tep.Editable = 'off';
            app.vypln_jmeno = uieditfield(app.AnalzaEKGTab, 'text');
            app.vypln_jmeno.Editable = 'off';
            app.vypln_jmeno.Visible = 'off';
            app.vek_vypln = uieditfield(app.AnalzaEKGTab, 'numeric');
            app.vek_vypln.Editable = 'off';
            app.vek_vypln.Visible = 'off';
            app.vypln_choroby = uitextarea(app.AnalzaEKGTab);
            app.vypln_choroby.Editable = 'off';
            app.vypln_choroby.Visible = 'off';
            app.PejtnazatekButton = uibutton(app.AnalzaEKGTab, 'push');
            app.PejtnazatekButton.ButtonPushedFcn = createCallbackFcn(app, @zacatek, true);
            app.PejtnazatekButton.Text = {'Přejít na '; 'začátek'};
            app.analyzaR_2 = uitextarea(app.AnalzaEKGTab);
            app.analyzaR_2.Editable = 'off';
            app.analyza_R_kmity = uitextarea(app.AnalzaEKGTab);
            app.analyza_R_kmity.Editable = 'off';
            app.Panel_6 = uipanel(app.AnalzaEKGTab);
            app.AnalzaEKGzezznamuLabel = uilabel(app.Panel_6);
            app.AnalzaEKGzezznamuLabel.Text = 'Analýza EKG ze záznamu';
            app.IDpacientaEditFieldLabel = uilabel(app.AnalzaEKGTab);
            app.IDpacientaEditFieldLabel.Text = 'ID pacienta';
            app.ID_num = uieditfield(app.AnalzaEKGTab, 'text');
            app.ID_num.ValueChangedFcn = createCallbackFcn(app, @zmena_ID, true);
            app.vypln_prijmeni = uieditfield(app.AnalzaEKGTab, 'text');
            app.vypln_prijmeni.Editable = 'off';
            app.Image = uiimage(app.AnalzaEKGTab);
            app.Image.ImageSource = 'srdce.png';
            app.peaks_zobrazovat = uibutton(app.AnalzaEKGTab, 'state');
            app.peaks_zobrazovat.ValueChangedFcn = createCallbackFcn(app, @peaks, true);
            app.peaks_zobrazovat.Text = 'Vyznačit peaky';
            app.Panel_7 = uipanel(app.AnalzaEKGTab);
            app.rytmus = uitextarea(app.AnalzaEKGTab);
            app.rytmus.Editable = 'off';
            app.semafor1 = uilamp(app.AnalzaEKGTab);
            app.semafor1.Visible = 'off';
            app.label_jmeno = uilabel(app.AnalzaEKGTab);
            app.label_jmeno.Visible = 'off';
            app.label_jmeno.Text = 'Jméno';
            app.label_vek = uilabel(app.AnalzaEKGTab);
            app.label_vek.Visible = 'off';
            app.label_vek.Text = 'Věk';
            app.label_choroby = uilabel(app.AnalzaEKGTab);
            app.label_choroby.Visible = 'off';
            app.label_choroby.Text = {'Prodělané'; 'srdeční choroby'};
            app.label_prijmeni = uilabel(app.AnalzaEKGTab);
            app.label_prijmeni.Text = 'Příjmení';
            app.vypln_analyza_tep = uitextarea(app.AnalzaEKGTab);
            app.vypln_analyza_tep.Editable = 'off';
            app.semafor4 = uilamp(app.AnalzaEKGTab);
            app.semafor4.Visible = 'off';
            app.NastavenTab = uitab(app.TabGroup);
            app.NastavenTab.Title = 'Nastavení';
            app.nacist_data = uibutton(app.NastavenTab, 'push');
            app.nacist_data.ButtonPushedFcn = createCallbackFcn(app, @nova_data, true);
            app.nacist_data.Text = {'Načíst data '; 'z nového souboru'};
            app.prijmeni_p = uicheckbox(app.NastavenTab);
            app.prijmeni_p.ValueChangedFcn = createCallbackFcn(app, @prijmeni_zmena, true);
            app.prijmeni_p.Text = 'Příjmení pacienta';
            app.prijmeni_p.Value = true;
            app.ZobrazovatLabel = uilabel(app.NastavenTab);
            app.ZobrazovatLabel.Text = 'Zobrazovat:';
            app.jmeno_p = uicheckbox(app.NastavenTab);
            app.jmeno_p.ValueChangedFcn = createCallbackFcn(app, @jmeno_zmena, true);
            app.jmeno_p.Text = 'Jméno pacienta';
            app.vek_p = uicheckbox(app.NastavenTab);
            app.vek_p.ValueChangedFcn = createCallbackFcn(app, @zmena_vek, true);
            app.vek_p.Text = 'Věk pacienta';
            app.prodelane_p = uicheckbox(app.NastavenTab);
            app.prodelane_p.ValueChangedFcn = createCallbackFcn(app, @zmena_chorby, true);
            app.prodelane_p.Text = 'Prodělané srdeční choroby';
            app.graf_barva = uibuttongroup(app.NastavenTab);
            app.graf_barva.SelectionChangedFcn = createCallbackFcn(app, @berva_zmena, true);
            app.graf_barva.Title = 'Barva grafu';
            app.cervena_g = uiradiobutton(app.graf_barva);
            app.cervena_g.Text = 'Červená';
            app.cervena_g.Value = true;
            app.zluta_g = uiradiobutton(app.graf_barva);
            app.zluta_g.Text = 'Žlutá';
            app.modra_g = uiradiobutton(app.graf_barva);
            app.modra_g.Text = 'Modrá';
            app.peak_barva = uibuttongroup(app.NastavenTab);
            app.peak_barva.SelectionChangedFcn = createCallbackFcn(app, @barva_peak_zmena, true);
            app.peak_barva.Title = 'Barva peaků';
            app.cervena_p = uiradiobutton(app.peak_barva);
            app.cervena_p.Text = 'Červená';
            app.zluta_p = uiradiobutton(app.peak_barva);
            app.zluta_p.Text = 'Žlutá';
            app.modra_p = uiradiobutton(app.peak_barva);
            app.modra_p.Text = 'Modrá';
            app.modra_p.Value = true;
            app.kurak = uicheckbox(app.NastavenTab);
            app.kurak.ValueChangedFcn = createCallbackFcn(app, @zmena_kurak, true);
            app.kurak.Text = 'Kuřák / nekuřák';
            app.Panel_4 = uipanel(app.NastavenTab);
            app.NastavenLabel = uilabel(app.Panel_4);
            app.NastavenLabel.Text = 'Nastavení';
            app.Image2 = uiimage(app.Panel_4);
            app.Image2.ImageSource = 'srdce.png';
            app.SloupeczekterhochcetenastasLabel = uilabel(app.NastavenTab);
            app.SloupeczekterhochcetenastasLabel.Text = 'Sloupec, ze kterého chcete načíst čas';
            app.cas_sloupec_pole = uieditfield(app.NastavenTab, 'numeric');
            app.cas_sloupec_pole.ValueChangedFcn = createCallbackFcn(app, @sloupec_cas_zmena, true);
            app.cas_sloupec_pole.Value = 1;
            app.SloupeczekterhochcetenastdataLabel = uilabel(app.NastavenTab);
            app.SloupeczekterhochcetenastdataLabel.Text = 'Sloupec, ze kterého chcete načíst data';
            app.data_sloupec_pole = uieditfield(app.NastavenTab, 'numeric');
            app.data_sloupec_pole.ValueChangedFcn = createCallbackFcn(app, @data_sloupec_zmena, true);
            app.data_sloupec_pole.Value = 2;
            app.PoetzobrazenchsekundLabel = uilabel(app.NastavenTab);
            app.PoetzobrazenchsekundLabel.Text = 'Počet zobrazených sekund';
            app.pocet_sekund_zobrezeni = uieditfield(app.NastavenTab, 'numeric');
            app.pocet_sekund_zobrezeni.Limits = [1 1000];
            app.pocet_sekund_zobrezeni.ValueChangedFcn = createCallbackFcn(app, @zmena_sekundy, true);
            app.pocet_sekund_zobrezeni.Value = 3;
            app.VypisovatnefyziologickhodnotydotextovhosouboruSwitchLabel = uilabel(app.NastavenTab);
            app.VypisovatnefyziologickhodnotydotextovhosouboruSwitchLabel.Text = 'Vypisovat nefyziologické hodnoty do textového souboru';
            app.vypisovat_do_souboru = uiswitch(app.NastavenTab, 'slider');
            app.vypisovat_do_souboru.Items = {'Vypnuto', 'Zapnuto'};
            app.vypisovat_do_souboru.ItemsData = {'0', '1'};
            app.vypisovat_do_souboru.ValueChangedFcn = createCallbackFcn(app, @vypisuj_do_souboru, true);
            app.vypisovat_do_souboru.Value = '1';
            app.Image3 = uiimage(app.NastavenTab);
            app.Image3.ImageSource = 'ekg_srdce (1).png';
            app.Image3_2 = uiimage(app.NastavenTab);
            app.Image3_2.ImageSource = 'ekg_srdce (1).png';
            app.Image3_4 = uiimage(app.NastavenTab);
            app.Image3_4.ImageSource = 'ekg_srdce (1).png';
            app.Image3_5 = uiimage(app.NastavenTab);
            app.Image3_5.ImageSource = 'ekg_srdce (1).png';
            app.Image3_6 = uiimage(app.NastavenTab);
            app.Image3_6.ImageSource = 'ekg_srdce (1).png';
            app.Image3_7 = uiimage(app.NastavenTab);
            app.Image3_7.ImageSource = 'ekg_srdce (1).png';
            app.NvratdoautorizaceButton = uibutton(app.NastavenTab, 'push');
            app.NvratdoautorizaceButton.ButtonPushedFcn = createCallbackFcn(app, @navrat, true);
            app.NvratdoautorizaceButton.Text = {'Návrat do'; 'autorizace'};
            app.UpozorovatnanefyziologickhodnotyvyskakovacmoknemSwitchLabel = uilabel(app.NastavenTab);
            app.UpozorovatnanefyziologickhodnotyvyskakovacmoknemSwitchLabel.Text = 'Upozorňovat na nefyziologické hodnoty vyskakovacím oknem';
            app.oznamovat = uiswitch(app.NastavenTab, 'slider');
            app.oznamovat.Items = {'Vypnuto', 'Zapnuto'};
            app.oznamovat.ValueChangedFcn = createCallbackFcn(app, @oznamuj, true);
            app.oznamovat.Value = 'Zapnuto';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_EKG_projekt(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end