classdef app_autorizace < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ZadejteIDpacientaEditFieldLabel  matlab.ui.control.Label
        ID_autorizace      matlab.ui.control.EditField
        ZdejteautoritankdEditFieldLabel  matlab.ui.control.Label
        kod                matlab.ui.control.EditField
        pokracovat         matlab.ui.control.Button
        databaze_tlacitko  matlab.ui.control.Button
        Panel              matlab.ui.container.Panel
        AnalzaEKGLabel     matlab.ui.control.Label
        Image              matlab.ui.control.Image
        Image_2            matlab.ui.control.Image
        Image_3            matlab.ui.control.Image
        chyba              matlab.ui.control.Label
        ok                 matlab.ui.control.Label
    end

    
    properties (Access = public)
        ID_cislo % Description
        databaze
        data
        prijmeni
        ID_pacienta
        hlavni
        pocitadlo
        otevri_databazi
        ID_prenos
        CallingApp
        pocet_hesel
        heslo
        delka
        zkontroluj
    end
    
    methods (Access = public)
                
        function posli_data(app,cislo)
           app.ID_pacienta.Value=cislo;
           app.ID_autorizace.Value=cislo;
           set(app.pokracovat, 'Enable', 'on');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.delka.Value = 1;
            app.zkontroluj.Value = 0;
            app.heslo.Value = char('');
            app.pocet_hesel.Value = 0; % inicializace počítání špatně zadaných hesel
            app.pocitadlo.Value = 0; % inicializace, aby nešlo pomocí enteru odkliknout pokračování před ověřením hesla a ID           
        end

        % Value changed function: ID_autorizace
        function ID_funkce(app, event)
            app.databaze.Value = readtable('databaze_pacientu.xlsx');
            
            app.data = table2struct(app.databaze.Value); % aby šlo s hodnotami pracovat
            
            app.ID_cislo.Value = str2double(app.ID_autorizace.Value); 
            % políčko pro ID je textové,
            % aby se nezobrazovalo jako desetinné číslo krát 10 na
            % několikátou
            
            app.ID_prenos.Value = app.ID_autorizace.Value;
                         
            for j = 1:height(app.databaze.Value)
                
                app.ID_pacienta.Value = app.data(j).ID;
                
                if app.ID_pacienta.Value == app.ID_cislo.Value
                    % ověří existenci ID v databázi a pustí uživatele dál
                    
                    set(app.pokracovat, 'Enable', 'on');
                    
                    app.prijmeni.Value = app.data(j).Prijmeni;
                                 
                    app.pocitadlo.Value = 1;  % vše je v pořádku, odkliknutí enterem může fungovat                  
                end
                
            end
            
            if app.pocitadlo.Value == 0
                    
                    app.ID_autorizace.Value = 'CHYBA!'; % špatně zadané ID
                    
            end                       
        end

        % Value changed function: kod
        function kod_funkce(app, event)
            set(app.chyba, 'Visible', 'off')
            set(app.ok, 'Visible', 'off')
            if length(app.kod.Value) == length('heslo')
                if app.kod.Value == char('heslo') % ověření hesla
                    app.kod.Value = '*****';
                    set(app.ID_autorizace, 'Editable', 'on')
                    set(app.databaze_tlacitko, 'Enable', 'on')
                    set(app.ok, 'Visible', 'on')
                else
                    app.kod.Value = ''; % šptaně zadané heslo
                    app.pocet_hesel.Value = app.pocet_hesel.Value + 1;
                    set(app.chyba, 'Visible', 'on')
                end
            else
                app.kod.Value = ''; % šptaně zadané heslo
                app.pocet_hesel.Value = app.pocet_hesel.Value + 1;
                set(app.chyba, 'Visible', 'on')
            end
            if app.pocet_hesel.Value >= 3 % pokud je špatné heslo zadáno mockrát, aplikace se zavře
                delete(app)
            end
            app.zkontroluj.Value = 0;
        end

        % Button pushed function: pokracovat
        function pokracuj(app, event)
            app.hlavni.Value = app_EKG_projekt(app.ID_pacienta.Value);

            delete(app)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            if app.zkontroluj.Value == 1
                kod_funkce(app)
            end
              
            if app.pocitadlo.Value == 1
                key = event.Key;
                switch event.Key
                    case 'return' % odkliknutí pokračování pomocí enteru
                       pokracuj(app)
                end
            elseif app.zkontroluj.Value == 1
                key = event.Key;
                switch event.Key
                    case 'return' % kontrola zadaného hesla
                        app.kod_funkce(app)
                end
            end
        end

        % Button pushed function: databaze_tlacitko
        function zobrazit_databazi(app, event)
             app.otevri_databazi.Value = app_databaze(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.ZadejteIDpacientaEditFieldLabel = uilabel(app.UIFigure);
            app.ZadejteIDpacientaEditFieldLabel.Text = 'Zadejte ID pacienta';
            app.ID_autorizace = uieditfield(app.UIFigure, 'text');
            app.ID_autorizace.ValueChangedFcn = createCallbackFcn(app, @ID_funkce, true);
            app.ID_autorizace.Editable = 'off';
            app.ZdejteautoritankdEditFieldLabel = uilabel(app.UIFigure);
            app.ZdejteautoritankdEditFieldLabel.Text = 'Zdejte autoritační kód';
            app.kod = uieditfield(app.UIFigure, 'text');
            app.kod.ValueChangedFcn = createCallbackFcn(app, @kod_funkce, true);
            app.pokracovat = uibutton(app.UIFigure, 'push');
            app.pokracovat.ButtonPushedFcn = createCallbackFcn(app, @pokracuj, true);
            app.pokracovat.Enable = 'off';
            app.pokracovat.Text = 'Pokračovat';
            app.databaze_tlacitko = uibutton(app.UIFigure, 'push');
            app.databaze_tlacitko.ButtonPushedFcn = createCallbackFcn(app, @zobrazit_databazi, true);
            app.databaze_tlacitko.Enable = 'off';
            app.databaze_tlacitko.Text = {'Výpis z'; 'databáze'};
            app.Panel = uipanel(app.UIFigure);
            app.AnalzaEKGLabel = uilabel(app.Panel);
            app.AnalzaEKGLabel.Text = 'Analýza EKG';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'ekg_srdce (1).png';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = 'ekg_srdce (1).png';
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.ImageSource = 'ekg_srdce (1).png';
            app.chyba = uilabel(app.UIFigure);
            app.chyba.Visible = 'off';
            app.chyba.Text = 'CHYBA!';
            app.ok = uilabel(app.UIFigure);
            app.ok.Visible = 'off';
            app.ok.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_autorizace

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end