classdef app_databaze < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UITable               matlab.ui.control.Table
        pridat_pacienta       matlab.ui.control.Button
        hotovo                matlab.ui.control.Button
        Panel                 matlab.ui.container.Panel
        VpiszadatabzeLabel    matlab.ui.control.Label
        VybrnoEditFieldLabel  matlab.ui.control.Label
        vybrano               matlab.ui.control.EditField
    end

    
    properties (Access = public)
        databaze % Description
        pridat
        ID_pacienta
        callingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app2)
            app.databaze.Value = readtable('databaze_pacientu.xlsx');
            %UPRAVENO ABY MI TO NACETLO DATA
            app.callingApp=app2;
            
            vars = {'ID', 'Prijmeni', 'Jmeno', 'DatumNarozeni'}; % názvy sloupců
            app.databaze.Value = app.databaze.Value(:, vars);
            app.UITable.Data = app.databaze.Value;
            
            app.UITable.FontName = 'Courier New';
            app.UITable.FontWeight = 'bold';
        end

        % Button pushed function: pridat_pacienta
        function pridej(app, event)
            app.pridat.Value = app_pridat_pacienta(app);
            delete(app);
        end

        % Button pushed function: hotovo
        function zavrit(app, event)
            %POSLANI DO APLIKACE AUTORIZACE
            posli_data(app.callingApp, app.vybrano.Value);

            delete(app)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            switch event.key
                case 'space' % odkliknutí hotovo spacem
                    delete(app)
            end
        end

        % Cell selection callback: UITable
        function vyber(app, event)
            % vezme index buňky, na kterou uživatel klikl, zjistí žádek a
            % ID, které se v něm nachází, uloží ho do proměnné a vypíše do
            % okýnka
            index = event.Indices;
            ID = table2cell(app.databaze.Value);
            ID2 = ID{index(1),1};
            app.vybrano.Value = num2str(ID2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'ID pacienta'; 'Příjmení pacienta'; 'Jméno pacienta'; 'Datum narození'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @vyber, true);
            app.pridat_pacienta = uibutton(app.UIFigure, 'push');
            app.pridat_pacienta.ButtonPushedFcn = createCallbackFcn(app, @pridej, true);
            app.pridat_pacienta.Text = 'Přidat pacienta';
            app.hotovo = uibutton(app.UIFigure, 'push');
            app.hotovo.ButtonPushedFcn = createCallbackFcn(app, @zavrit, true);
            app.hotovo.Text = 'Hotovo';
            app.Panel = uipanel(app.UIFigure);
            app.VpiszadatabzeLabel = uilabel(app.Panel);
            app.VpiszadatabzeLabel.Text = 'Výpis za databáze';
            app.VybrnoEditFieldLabel = uilabel(app.UIFigure);
            app.VybrnoEditFieldLabel.Text = 'Vybráno';
            app.vybrano = uieditfield(app.UIFigure, 'text');
            app.vybrano.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_databaze(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end