classdef app_nepravidelny_rytmus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        srdecni_rytmus          matlab.ui.control.Label
        HotovoButton            matlab.ui.control.Button
        ZkontrolujtevytvoentexrovsouborLabel  matlab.ui.control.Label
        amplituda_pravidelnost  matlab.ui.control.Label
        amplituda_vyska         matlab.ui.control.Label
        tep                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        vektor_nepravidelnosti % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, alerty_analyza)
            app.vektor_nepravidelnosti.Value = alerty_analyza;
            % zobrazí hlášky jen o tom, co bylo vyhodnoceno jako
            % nefyziologické
            if app.vektor_nepravidelnosti.Value(1,1) == 1
                set(app.srdecni_rytmus, 'Visible', 'on');
            end
            if app.vektor_nepravidelnosti.Value(1,2) == 1
                set(app.amplituda_pravidelnost, 'Visible', 'on');
            end
            if app.vektor_nepravidelnosti.Value(1,3) == 1
                set(app.amplituda_vyska, 'Visible', 'on');
            end
            if app.vektor_nepravidelnosti.Value(1,4) == 1
                set(app.tep, 'Visible', 'on');
            end
        end

        % Button pushed function: HotovoButton
        function hotovo(app, event)
            delete(app)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch event.Key
                case 'space' % odkliknutí hotovo spacem
                    delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.srdecni_rytmus = uilabel(app.UIFigure);
            app.srdecni_rytmus.Visible = 'off';
            app.srdecni_rytmus.Text = 'Srdeční rytmus je nepravidelný!';
            app.HotovoButton = uibutton(app.UIFigure, 'push');
            app.HotovoButton.ButtonPushedFcn = createCallbackFcn(app, @hotovo, true);
            app.HotovoButton.Text = 'Hotovo';
            app.ZkontrolujtevytvoentexrovsouborLabel = uilabel(app.UIFigure);
            app.ZkontrolujtevytvoentexrovsouborLabel.Text = {'Zkontrolujte'; 'vytvořený texrový soubor!'};
            app.amplituda_pravidelnost = uilabel(app.UIFigure);
            app.amplituda_pravidelnost.Visible = 'off';
            app.amplituda_pravidelnost.Text = 'Amplituda R kmitů je nepravidelná!';
            app.amplituda_vyska = uilabel(app.UIFigure);
            app.amplituda_vyska.Visible = 'off';
            app.amplituda_vyska.Text = 'Amplituda R kmitů je mimo fyziologické hodnoty!';
            app.tep = uilabel(app.UIFigure);
            app.tep.Visible = 'off';
            app.tep.Text = 'Tepová frekvence je mimo fyziologické hodnoty!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_nepravidelny_rytmus(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end