classdef app_pridat_pacienta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        IDEditFieldLabel              matlab.ui.control.Label
        IDEditField                   matlab.ui.control.EditField
        PjmenEditFieldLabel           matlab.ui.control.Label
        prijmeni                      matlab.ui.control.EditField
        JmnoEditFieldLabel            matlab.ui.control.Label
        Jmeno1                        matlab.ui.control.EditField
        DatumnarozenDatePickerLabel   matlab.ui.control.Label
        datum                         matlab.ui.control.DatePicker
        SystolicktlakEditFieldLabel   matlab.ui.control.Label
        systolicky                    matlab.ui.control.NumericEditField
        DiastolicktlakEditFieldLabel  matlab.ui.control.Label
        diastolicky                   matlab.ui.control.NumericEditField
        HotovoButton                  matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        PidatpacientadodatabzeLabel   matlab.ui.control.Label
        VkaLabel                      matlab.ui.control.Label
        vyska                         matlab.ui.control.Spinner
        VhaSpinnerLabel               matlab.ui.control.Label
        vaha                          matlab.ui.control.Spinner
        ProdlansrdenchorobyLabel      matlab.ui.control.Label
        choroby                       matlab.ui.control.TextArea
        kurak_ano_ne                  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        cislo_ID % Description
        databaze_appka
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, databaze)
            app.databaze_appka = databaze;
        end

        % Button pushed function: HotovoButton
        function hotovo(app, event)
            app.cislo_ID.Value = str2double(app.IDEditField.Value); % stejně jako všude, textové pole pro ID
            
            % matlab chce data, co posílá do excelu, jako structy, tak jsou to structy
            ID = app.cislo_ID.Value;
            Prijmeni = {app.prijmeni.Value};
            Jmeno = {app.Jmeno1.Value};
            DatumNarozeni = app.datum.Value;
            Sys = app.systolicky.Value;
            Dias = app.diastolicky.Value;
            Vyska = app.vyska.Value;
            Vaha = app.vaha.Value;
            Tep = 0;
            Ryt = 0;
            Prodelane = app.choroby.Value;
            Kurak = app.kurak_ano_ne.Value;
            
            % názvy sloupců v excelu
            t = table(ID, Prijmeni, Jmeno, DatumNarozeni, Sys, Dias, Vyska, Vaha, Tep, Ryt, Prodelane, Kurak);
            
            % writemode append je aby to napsalo nakonec tabulky a nic mi
            % tam nepřepisovalo
            writetable(t, 'databaze_pacientu.xlsx','WriteMode', 'Append' ,'WriteVariableNames', false , "WriteRowNames", true);
            
            % nedaří se mi zavolat zpátky databázi, aniž by mi to psalo chybu
            
            delete(app);            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            switch event.Key
                case 'return' % odkliknutí pomocí enteru
                    hotovo(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.IDEditFieldLabel = uilabel(app.UIFigure);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.UIFigure, 'text');
            app.PjmenEditFieldLabel = uilabel(app.UIFigure);
            app.PjmenEditFieldLabel.Text = 'Příjmení';
            app.prijmeni = uieditfield(app.UIFigure, 'text');
            app.JmnoEditFieldLabel = uilabel(app.UIFigure);
            app.JmnoEditFieldLabel.Text = 'Jméno';
            app.Jmeno1 = uieditfield(app.UIFigure, 'text');
            app.DatumnarozenDatePickerLabel = uilabel(app.UIFigure);
            app.DatumnarozenDatePickerLabel.Text = 'Datum narození';
            app.datum = uidatepicker(app.UIFigure);
            app.SystolicktlakEditFieldLabel = uilabel(app.UIFigure);
            app.SystolicktlakEditFieldLabel.Text = 'Systolický tlak';
            app.systolicky = uieditfield(app.UIFigure, 'numeric');
            app.DiastolicktlakEditFieldLabel = uilabel(app.UIFigure);
            app.DiastolicktlakEditFieldLabel.Text = 'Diastolický tlak';
            app.diastolicky = uieditfield(app.UIFigure, 'numeric');
            app.HotovoButton = uibutton(app.UIFigure, 'push');
            app.HotovoButton.ButtonPushedFcn = createCallbackFcn(app, @hotovo, true);
            app.HotovoButton.Text = 'Hotovo';
            app.Panel = uipanel(app.UIFigure);
            app.PidatpacientadodatabzeLabel = uilabel(app.Panel);
            app.PidatpacientadodatabzeLabel.Text = 'Přidat pacienta do databáze';
            app.VkaLabel = uilabel(app.UIFigure);
            app.VkaLabel.Text = 'Výška';
            app.vyska = uispinner(app.UIFigure);
            app.vyska.Limits = [0 Inf];
            app.vyska.Value = 170;
            app.VhaSpinnerLabel = uilabel(app.UIFigure);
            app.VhaSpinnerLabel.Text = 'Váha';
            app.vaha = uispinner(app.UIFigure);
            app.vaha.Limits = [0 Inf];
            app.vaha.Value = 70;
            app.ProdlansrdenchorobyLabel = uilabel(app.UIFigure);
            app.ProdlansrdenchorobyLabel.Text = {'Prodělané'; 'srdeční'; 'choroby'};
            app.choroby = uitextarea(app.UIFigure);
            app.kurak_ano_ne = uicheckbox(app.UIFigure);
            app.kurak_ano_ne.Text = 'Kuřák';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_pridat_pacienta(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end